classdef InferenceApp_R2024b < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        GridLayout      matlab.ui.container.GridLayout
        GridLayout2     matlab.ui.container.GridLayout
        HeatmapButton   matlab.ui.control.Button
        CameraButton    matlab.ui.control.Button
        GridLayout3     matlab.ui.container.GridLayout
        CrackLamp       matlab.ui.control.Lamp
        CrackLampLabel  matlab.ui.control.Label
        DisplayImage    matlab.ui.control.Image
        ProcessStatus   matlab.ui.control.Label
    end

    
    properties (Access = private)
        %Azure
        azrFQDN = "sko2025demo.gma4d4cmgvfndxft.eastus.azurecontainer.io";
        azrPrtN = "9910";
        azrInst = "sko2025demo";
        fcnNm   = "isCrackPresentMat";

        hmpFQDN = "heatmapdemo.c8hbajhjdee6cxev.eastus.azurecontainer.io";
        hmpPrtN = "9910";
        hmpInst = "heatmapdemo";
        hmpNm   = "getCrackHeatmap";
        
        %Http
        httpUrl
        httpOpt
        httpData
        htmpUrl

        %Files
        dataFldr="C:\sandbox\NonCSGProject\visual-inspection-demo-main\data";
        imgFiles
        fileN
        sentI
        badFlTag= "\crack\masks\";
        
        %Runtime
        status = "normal";
        statusHmp = "normal";   
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % relevant to Image
            imds= imageDatastore(app.dataFldr,"IncludeSubfolders",true);
            app.imgFiles= imds.Files;
            bdIdx   = contains(app.imgFiles,app.badFlTag);
            app.imgFiles= app.imgFiles(~bdIdx);
            app.fileN   = numel(app.imgFiles);

            % relevant to http call
            app.httpOpt = weboptions(...
                "MediaType","application/json","Timeout",30);
            urlL        = "http://"+app.azrFQDN+":"+app.azrPrtN+"/";
            app.httpUrl = fullfile(urlL,app.azrInst,app.fcnNm);
            
            urlHmp      = "http://"+app.hmpFQDN+":"+app.hmpPrtN+"/";
            app.htmpUrl = fullfile(urlHmp,app.hmpInst,app.hmpNm);

            %Dummy
            dmmI= zeros(110,61,3);
            app.DisplayImage.ImageSource = dmmI;
        end

        % Button pushed function: CameraButton
        function CameraButtonPushed(app, event)
            if app.status ~= "retry"
                idx = randi(app.fileN,1);
                I   = imread(app.imgFiles{idx});
                app.DisplayImage.ImageSource = I;
                pause(0.01)

                app.ProcessStatus.Text = "Processing on Azure";
                app.CrackLamp.Color    = [1,1,0];
                pause(0.01)

                app.sentI = imresize(I,0.4);
                sizeMeta.mwdata     = size(app.sentI);
                sizeMeta.mwsize     = size(size(app.sentI));
                sizeMeta.mwtype     = class(size(app.sentI));

                matrixMeta.mwdata   = app.sentI(:);
                matrixMeta.mwsize   = size(app.sentI(:));
                matrixMeta.mwtype   = class(app.sentI);

                inJson.nargout  = 1;
                inJson.rhs      = [sizeMeta,matrixMeta];
                app.httpData     = jsonencode(inJson);
            end

            try
                tic
                resp = webwrite(app.httpUrl,app.httpData,app.httpOpt);
                et  = toc;
                et  = sprintf("%0.2f",et);

                app.ProcessStatus.Text = "Result from Azure"+"("+et+"sec)";
                if resp.lhs.mwdata
                    app.CrackLamp.Color    = [1,0,0];
                else
                    app.CrackLamp.Color    = [0,1,0];
                end
                app.status = "normal";
                app.CameraButton.Text  = "Camera";
            catch me
                app.ProcessStatus.Text = "TRY AGAIN!!";
                app.CameraButton.Text  = "Retry";
                app.CrackLamp.Color    = [0.94,0.94,0.94];
                app.status = "retry";
            end
        end

        % Button pushed function: HeatmapButton
        function HeatmapButtonPushed(app, event)
            if app.statusHmp ~= "retry"
                idx = randi(app.fileN,1);
                I   = imread(app.imgFiles{idx});
                app.DisplayImage.ImageSource = I;
                pause(0.01)

                app.ProcessStatus.Text = "Processing on Azure";
                app.CrackLamp.Color    = [1,1,0];
                pause(0.01)

                app.sentI = imresize(I,0.4);
                sizeMeta.mwdata     = size(app.sentI);
                sizeMeta.mwsize     = size(size(app.sentI));
                sizeMeta.mwtype     = class(size(app.sentI));

                matrixMeta.mwdata   = app.sentI(:);
                matrixMeta.mwsize   = size(app.sentI(:));
                matrixMeta.mwtype   = class(app.sentI);

                inJson.nargout  = 2;
                inJson.rhs      = [sizeMeta,matrixMeta];
                app.httpData     = jsonencode(inJson);
            end

            try
                tic
                resp = webwrite(app.htmpUrl,app.httpData,app.httpOpt);
                et  = toc;
                et  = sprintf("%0.2f",et);

                app.ProcessStatus.Text = "Result from Azure"+"("+et+"sec)";
                if resp.lhs(1).mwdata
                    app.CrackLamp.Color    = [1,0,0];
                else
                    app.CrackLamp.Color    = [0,1,0];
                end
                
                %Heatmap image
                Iout= uint8(reshape(resp.lhs(2).mwdata,resp.lhs(2).mwsize'));
                app.DisplayImage.ImageSource = Iout;

                app.statusHmp = "normal";
                app.HeatmapButton.Text = "Heatmap";
            catch me
                app.ProcessStatus.Text = "TRY AGAIN!!";
                app.HeatmapButton.Text = "Retry";
                app.CrackLamp.Color    = [0.94,0.94,0.94];
                app.statusHmp = "retry";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ProcessStatus = uilabel(app.GridLayout);
            app.ProcessStatus.Text = 'Take a Photo';
            app.DisplayImage = uiimage(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.CrackLampLabel = uilabel(app.GridLayout3);
            app.CrackLampLabel.Text = 'Crack: ';
            app.CrackLamp = uilamp(app.GridLayout3);
            app.CameraButton = uibutton(app.GridLayout2, 'push');
            app.CameraButton.ButtonPushedFcn = createCallbackFcn(app, @CameraButtonPushed, true);
            app.CameraButton.Text = 'Camera';
            app.HeatmapButton = uibutton(app.GridLayout2, 'push');
            app.HeatmapButton.ButtonPushedFcn = createCallbackFcn(app, @HeatmapButtonPushed, true);
            app.HeatmapButton.Text = 'Heatmap';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InferenceApp_R2024b

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end